classdef ManualCorrect < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        Title                          matlab.ui.control.EditField
        ClickonanimagetoselectitformanualcorrectionofpeakLabel  matlab.ui.control.Label
        AlternativeCandidateLabel      matlab.ui.control.Label
        SuggestedCandidateLabel        matlab.ui.control.Label
        ReferenceImagedonotclickLabel  matlab.ui.control.Label
        ImgRef                         matlab.ui.control.Image
        ImgRight                       matlab.ui.control.Image
        ImgLeft                        matlab.ui.control.Image
    end

    
    properties (Access = private)
        CallingApp % Main app
        offset_1
        offset_2
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, caller, path, pk, offset_1, offset_2)
            app.CallingApp = caller;
            app.offset_1 = offset_1;
            app.offset_2 = offset_2;

            img_ref = imread(path, app.CallingApp.Phase.Value);
            img_left = imread(path, offset_1);
            img_right = imread(path, offset_2);
            
            app.Title.Value = num2str(pk);
            %myString = sprintf('Click on an image to select it for manual correction of peak %d', pk);
            %set(app.Title.Text, myString);
            app.ImgRef.ImageSource = repmat(img_ref, 1, 1, 3);
            app.ImgLeft.ImageSource = repmat(img_left, 1, 1, 3);
            app.ImgRight.ImageSource = repmat(img_right, 1, 1, 3);
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            uiresume;
            delete(app)
            
        end

        % Image clicked function: ImgLeft
        function ImgLeftImageClicked(app, event)
            UpdateOffset(app.CallingApp, app.offset_1);
            uiresume;
            delete(app)
        end

        % Image clicked function: ImgRight
        function ImgRightImageClicked(app, event)
            UpdateOffset(app.CallingApp, app.offset_2);
            uiresume;
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.ImgLeft = uiimage(app.UIFigure);
            app.ImgLeft.ImageClickedFcn = createCallbackFcn(app, @ImgLeftImageClicked, true);
            app.ImgRight = uiimage(app.UIFigure);
            app.ImgRight.ImageClickedFcn = createCallbackFcn(app, @ImgRightImageClicked, true);
            app.ImgRef = uiimage(app.UIFigure);
            app.ReferenceImagedonotclickLabel = uilabel(app.UIFigure);
            app.ReferenceImagedonotclickLabel.Interpreter = 'latex';
            app.ReferenceImagedonotclickLabel.Text = 'Reference Image (do not click)';
            app.SuggestedCandidateLabel = uilabel(app.UIFigure);
            app.SuggestedCandidateLabel.Interpreter = 'latex';
            app.SuggestedCandidateLabel.Text = 'Suggested Candidate';
            app.AlternativeCandidateLabel = uilabel(app.UIFigure);
            app.AlternativeCandidateLabel.Interpreter = 'latex';
            app.AlternativeCandidateLabel.Text = 'Alternative Candidate';
            app.ClickonanimagetoselectitformanualcorrectionofpeakLabel = uilabel(app.UIFigure);
            app.ClickonanimagetoselectitformanualcorrectionofpeakLabel.Text = 'Click on an image to select it for manual correction of peak #';
            app.Title = uieditfield(app.UIFigure, 'text');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ManualCorrect(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end