classdef PhaseMatching < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        InputPanel                     matlab.ui.container.Panel
        FolderPath                     matlab.ui.control.EditField
        BrowseFolder                   matlab.ui.control.Button
        FilenameCh1                    matlab.ui.control.EditField
        BrowseCh1                      matlab.ui.control.Button
        FilenameCh2                    matlab.ui.control.EditField
        BrowseCh2                      matlab.ui.control.Button
        FilenameCh3                    matlab.ui.control.EditField
        Channel1Label_2                matlab.ui.control.Label
        BrowseCh3                      matlab.ui.control.Button
        MainCh                         matlab.ui.control.Spinner
        MainChSpinnerLabel             matlab.ui.control.Label
        Phase                          matlab.ui.control.NumericEditField
        PhaseEditFieldLabel            matlab.ui.control.Label
        Channel2Label                  matlab.ui.control.Label
        SetButton                      matlab.ui.control.Button
        Channel1Label                  matlab.ui.control.Label
        DirectoryEditFieldLabel        matlab.ui.control.Label
        PreliminaryPhaseMatchingPanel  matlab.ui.container.Panel
        FindPeaks                      matlab.ui.control.Button
        RunPrelim                      matlab.ui.control.Button
        NumScales                      matlab.ui.control.NumericEditField
        NumScalesEditFieldLabel        matlab.ui.control.Label
        MinPeakHeight                  matlab.ui.control.NumericEditField
        MinPeakProminence              matlab.ui.control.NumericEditField
        MinPeakProminenceEditFieldLabel  matlab.ui.control.Label
        MinPeakHeightEditFieldLabel    matlab.ui.control.Label
        EnhancedPhaseMatchingPanel     matlab.ui.container.ButtonGroup
        CheckNeighboursEditFieldLabel  matlab.ui.control.Label
        AutoCorrect                    matlab.ui.control.RadioButton
        HybridCorrect                  matlab.ui.control.RadioButton
        Padding                        matlab.ui.control.NumericEditField
        CheckNeighbours                matlab.ui.control.NumericEditField
        Threshold                      matlab.ui.control.NumericEditField
        ThresholdLabel                 matlab.ui.control.Label
        PaddingEditFieldLabel          matlab.ui.control.Label
        RunROIMatch                    matlab.ui.control.Button
        OutputPanel                    matlab.ui.container.ButtonGroup
        OutputName                     matlab.ui.control.EditField
        OutputNameLabel                matlab.ui.control.Label
        MultiTiff                      matlab.ui.control.RadioButton
        PhaseOme                       matlab.ui.control.RadioButton
        SingleOme                      matlab.ui.control.RadioButton
        SaveButton                     matlab.ui.control.Button
    end

    properties (Access = private)
        pathnames           % structure containing full pathname
        pathnameChMain      % full pathname for main reference channel
        N_channels          % number of channels we have inputted data for
        img_ref             % reference image
        tifInfo             % information about data
        ssim_score_lst      % list of ssim_scores
        pk_locs             % location of peaks
        N_pks               % number of peaks
        cutLength           % number of frames
        DialogApp           % Dialog box app
        offset              % offset from reference for correction
        ImagesToSave        % images to save structure
    end

    methods (Access = public)     
        function UpdateOffset(app, selected)
            app.offset = selected;
            %msgbox(app.offset);
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Callback function: BrowseFolder, FolderPath
        function BrowseButtonPushed(app, event)
            app.FolderPath.Value = strcat(string(uigetdir),'\');
        end

        % Button pushed function: BrowseCh1
        function BrowseCh1ButtonPushed(app, event)
             app.FilenameCh1.Value = string( ...
                uigetfile({'*.tif','*.ome'}, ...
                'Select a File', app.FolderPath.Value) ...
                );
        end

        % Button pushed function: BrowseCh2
        function BrowseCh2ButtonPushed(app, event)
            app.FilenameCh2.Value = string( ...
                uigetfile({'*.tif','*.ome'}, ...
                'Select a File', app.FolderPath.Value) ...
                );
        end

        % Button pushed function: BrowseCh3
        function BrowseCh3ButtonPushed(app, event)
            app.FilenameCh3.Value = string( ...
                uigetfile({'*.tif','*.ome'}, ...
                'Select a File', app.FolderPath.Value) ...
                );
        end

        % Button pushed function: SetButton
        function SetButtonPushed(app, event)
%             app.pathnames = {
%                 strcat(app.FolderPath.Value, app.FilenameCh1.Value), ...
%                 strcat(app.FolderPath.Value, app.FilenameCh2.Value), ...
%                 strcat(app.FolderPath.Value, app.FilenameCh3.Value)
%                 };
%             app.N_channels = sum([ ...
%                 ~isempty(app.FilenameCh1.Value), ...
%                 ~isempty(app.FilenameCh2.Value), ...
%                 ~isempty(app.FilenameCh3.Value)]);
%             app.pathnameChMain = app.pathnames{app.MainCh.Value};
%             app.tifInfo = imfinfo(app.pathnameChMain);
%             img_ref = imread(app.pathnameChMain, app.Phase.Value);
            [app.pathnames, app.N_channels, app.pathnameChMain, app.tifInfo, app.img_ref] = ... 
                initialise(app.FolderPath.Value, ...
                app.FilenameCh1.Value, app.FilenameCh2.Value, app.FilenameCh3.Value, ...
                app.MainCh.Value, app.Phase.Value);
            figure(1);
            imshow(app.img_ref);
            title('This is our reference image for phase matching.')
        end

        % Button pushed function: RunPrelim
        function RunPrelimPushed(app, event)
            delete(msg);
            msg = msgbox('Calculating similarity to reference image. Please wait...');
%             N = length(app.tifInfo);
%             app.ssim_score_lst = zeros(N, 1);
%             app.img_ref = imread(app.pathnameChMain, app.Phase.Value); % reference image
% 
%             for i_Frame = 1:N
%                 img_sug = imread(app.pathnameChMain, i_Frame); % comparison image
%                 ssim_score = multissim3(img_sug, app.img_ref, NumScales=app.NumScales.Value);
%                 app.ssim_score_lst(i_Frame) = ssim_score;
%             end
            [app.ssim_score_lst, N] = ... 
                pre_phase_matching(app.tifInfo, app.pathnameChMain, app.img_ref, app.NumScales.Value);
            figure(2);
            plot(1:N, app.ssim_score_lst);
            title('ssim scores');
            hold on;
            delete(msg);
            msgbox('Similarity score calculated. Please proceed to find peaks.')
        end

        % Button pushed function: FindPeaks
        function RunFindPeaks(app, event)
            delete(msg);
            msg = msgbox('Finding peaks. Please wait...');
%             [pks, app.pk_locs] = findpeaks(app.ssim_score_lst, ...
%                 MinPeakHeight=app.MinPeakHeight.Value, ...
%                 MinPeakProminence=app.MinPeakProminence.Value);
%             app.N_pks = length(app.pk_locs);
% 
%             if app.Phase.Value == 1
%                 app.pk_locs = [1; app.pk_locs];
%                 pks = [app.ssim_score_lst(1); pks];
%             end
            [pks, app.pk_locs, app.N_pks] = ... 
                find_peaks(app.ssim_score_lst, app.MinPeakHeight.Value, app.MinPeakProminence.Value, app.Phase.Value);
            figure(2);
            plot(app.pk_locs, pks, "*")
            hold on;
            delete(msg);
            msg = msgbox([num2str(app.N_pks), ' peaks found. Please proceed to do advanced phase matching.']);
        end

        % Button pushed function: RunROIMatch
        function RunROIGoodPeaksPushed(app, event)
            msgbox('Initiating phase matching, select ROI in figure 1.')

            % Initialisation - cut images into one cardiac cycle + n frames
            distance_btwn_peaks = diff(app.pk_locs);
            meanDist = mean(distance_btwn_peaks); %Average number of frames between peaks
            numExtraImages = app.Padding.Value;
            app.cutLength = round(meanDist/2 + numExtraImages);
            app.ImagesToSave = cell(app.N_channels, app.N_pks,2*app.cutLength+1);

            % Show image for user to pick region of interest
            app.img_ref = imread(app.pathnameChMain, app.Phase.Value);
            figure(1);
            imshow(app.img_ref);
            title('Please draw a ROI to focus on here.')
            roi = drawrectangle;
            xbounds = ceil(roi.Position(1)):floor(roi.Position(1)+roi.Position(3));
            ybounds = ceil(roi.Position(2)):floor(roi.Position(2)+roi.Position(4));
            n_neighbours = app.CheckNeighbours.Value; % This algorithm is good enough that we don't need to check many neighbours
            
            msgbox('Performing enhanced phase matching. Please wait...')
            % Perform corrections depending on mode
            switch app.EnhancedPhaseMatchingPanel.SelectedObject.Tag
                case 'AutoCorrect'
                    app.offset = 0;
                    for i = 1:app.N_pks-1
                        if i~= 1
                            ssim_vals = auto_correct(app.pathnameChMain, xbounds, ybounds, app.Phase.Value, app.pk_locs(i), n_neighbours);
                            [M_1, I_1] = max(ssim_vals);
                            app.offset = I_1-n_neighbours-1;
                        end
                        k = 0;
                        for j = -app.cutLength: 1: app.cutLength
                            k = k + 1;
                            frame_idx = app.pk_locs(i)+j+app.offset;
                            for ch = 1:app.N_channels
                                if frame_idx >= 1 && frame_idx <= length(app.tifInfo)
                                    app.ImagesToSave{ch, i, k} = imread(app.pathnames{ch}, frame_idx);
                                else
                                    app.ImagesToSave{ch, i, k} = zeros(app.tifInfo(1).Height, app.tifInfo(1).Width);
                                end
                            end
                        end
                    end

                case 'HybridCorrect'
                    app.offset = 0;
                    for i = 1:app.N_pks-1
                        if i~= 1
                            ssim_vals = auto_correct(app.pathnameChMain, xbounds, ybounds, app.Phase.Value, app.pk_locs(i), n_neighbours);
                            [M_1, I_1] = max(ssim_vals);
                            offset_1 = I_1-n_neighbours-1;
                            ssim_vals(I_1) = 0;
                            [M_2, I_2] = max(ssim_vals);
                            offset_2 = I_2-n_neighbours-1;
                            if (M_1 - M_2)/M_1 < app.Threshold.Value/100 % If close call, get user to select. Add tolerance option???
                                %msgbox(i)
                                app.DialogApp = ManualCorrect(app, ...
                                    app.pathnameChMain, app.pk_locs(i), offset_1, offset_2);
                                uiwait;
                            else
                                app.offset = offset_1;
                            end
                        end
                        k = 0;
                        for j = -app.cutLength: 1: app.cutLength
                            k = k + 1;
                            frame_idx = app.pk_locs(i)+j+app.offset;
                            for ch = 1:app.N_channels
                                if frame_idx >= 1 && frame_idx <= length(app.tifInfo)
                                    app.ImagesToSave{ch, i, k} = imread(app.pathnames{ch}, frame_idx);
                                else
                                    app.ImagesToSave{ch, i, k} = zeros(app.tifInfo(1).Height, app.tifInfo(1).Width);
                                end
                            end
                        end
                    end

                otherwise
                    msgbox('Selected option not implemented.')                    
            end

            msgbox('Phase matching complete. Select save option.')
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            msgbox('Saving files');
            outputFolder = app.OutputName.Value;
            outputFileName = app.OutputName.Value;
            folder_w = [app.FolderPath.Value, outputFolder];
            mkdir(folder_w);

            switch app.OutputPanel.SelectedObject.Tag
                case 'MultiTiff'
                    for k3 = 1:2*app.cutLength+1
                        for k2 = 1:app.N_pks-1
                            for ch = 1:app.N_channels
                                ImageFile=[outputFileName '_t' padnumber(3,num2str(k2)) '_z' padnumber(3,num2str(k3)) '_c00' num2str(ch) '.tif'];
                                imwrite(app.ImagesToSave{ch,k2,k3}, [folder_w,filesep,ImageFile],'tif')
                            end
                            %ImageFile=[outputFileName '_t' padnumber(3,num2str(k2)) '_z' padnumber(3,num2str(k3)) '_c001.tif'];
                            %imwrite(app.ImagesToSaveCh1{k2,k3}, [folder_w,filesep,ImageFile],'tif')
                        end
                    end

                case 'PhaseOme'
                    javaaddpath('loci_tools.jar')
                    im=zeros(app.tifInfo(1).Height,app.tifInfo(1).Width, ...
                        app.N_pks,app.N_channels,1,'uint8'); %change to uint16 if using 16 bit images
                    for k2 = 1:app.N_pks-1
                        k3 = app.cutLength+1;
                        for ch = 1:app.N_channels
                            im(:,:,k2,ch,1) = app.ImagesToSave{ch,k2,k3};
                        end
                    end
                        ImageFile=[outputFileName '_z' padnumber(3,num2str(app.Phase.Value)) '.ome'];
                        bfsave(im, [folder_w,filesep,ImageFile])

                case 'SingleOme'
                    javaaddpath('loci_tools.jar')
                    im=zeros(app.tifInfo(1).Height,app.tifInfo(1).Width, ...
                        app.N_pks,app.N_channels,2*app.cutLength+1,'uint8'); %change to uint16 if using 16 bit images   
                    for k2 = 1:app.N_pks-1
                        for k3 = 1:2*app.cutLength+1
                            for ch = 1:app.N_channels
                                im(:,:,k2,ch,k3) = app.ImagesToSave{ch,k2,k3};
                            end
                        end
                    end
                        ImageFile = [outputFileName '_singleFile.ome'];
                        bfsave(im, [folder_w, filesep, ImageFile])
            end

            msgbox('Files saved');
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app.DialogApp)
            close all;
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.OutputPanel = uibuttongroup(app.UIFigure);
            app.OutputPanel.Title = 'Output';
            app.SaveButton = uibutton(app.OutputPanel, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.SingleOme = uiradiobutton(app.OutputPanel);
            app.SingleOme.Tag = 'SingleOme';
            app.SingleOme.Text = 'Save as single ome';
            app.PhaseOme = uiradiobutton(app.OutputPanel);
            app.PhaseOme.Tag = 'PhaseOme';
            app.PhaseOme.Text = 'Save as phase ome';
            app.MultiTiff = uiradiobutton(app.OutputPanel);
            app.MultiTiff.Tag = 'MultiTiff';
            app.MultiTiff.Text = 'Save as individual tiffs';
            app.MultiTiff.Value = true;
            app.OutputNameLabel = uilabel(app.OutputPanel);
            app.OutputNameLabel.Text = 'Output Name';
            app.OutputName = uieditfield(app.OutputPanel, 'text');
            app.OutputName.Value = 'PhaseMatchingOutput';
            app.EnhancedPhaseMatchingPanel = uibuttongroup(app.UIFigure);
            app.EnhancedPhaseMatchingPanel.Title = 'ROI Based Phase Matching';
            app.RunROIMatch = uibutton(app.EnhancedPhaseMatchingPanel, 'push');
            app.RunROIMatch.ButtonPushedFcn = createCallbackFcn(app, @RunROIGoodPeaksPushed, true);
            app.RunROIMatch.Text = 'Run';
            app.PaddingEditFieldLabel = uilabel(app.EnhancedPhaseMatchingPanel);
            app.PaddingEditFieldLabel.Text = 'Padding';
            app.ThresholdLabel = uilabel(app.EnhancedPhaseMatchingPanel);
            app.ThresholdLabel.Text = 'Threshold %';
            app.Threshold = uieditfield(app.EnhancedPhaseMatchingPanel, 'numeric');
            app.Threshold.Limits = [0 100];
            app.Threshold.Value = 5;
            app.CheckNeighbours = uieditfield(app.EnhancedPhaseMatchingPanel, 'numeric');
            app.CheckNeighbours.Limits = [1 Inf];
            app.CheckNeighbours.Value = 2;
            app.Padding = uieditfield(app.EnhancedPhaseMatchingPanel, 'numeric');
            app.Padding.Limits = [0 Inf];
            app.Padding.RoundFractionalValues = 'on';
            app.Padding.Value = 3;
            app.HybridCorrect = uiradiobutton(app.EnhancedPhaseMatchingPanel);
            app.HybridCorrect.Tag = 'HybridCorrect';
            app.HybridCorrect.Text = 'Hybrid correction (only requires correction for close calls)';
            app.AutoCorrect = uiradiobutton(app.EnhancedPhaseMatchingPanel);
            app.AutoCorrect.Tag = 'AutoCorrect';
            app.AutoCorrect.Text = 'Automatic correction (may not be optimised)';
            app.AutoCorrect.Value = true;
            app.CheckNeighboursEditFieldLabel = uilabel(app.EnhancedPhaseMatchingPanel);
            app.CheckNeighboursEditFieldLabel.Text = 'Check Neighbours';
            app.PreliminaryPhaseMatchingPanel = uipanel(app.UIFigure);
            app.PreliminaryPhaseMatchingPanel.Title = 'Preliminary Phase Matching';
            app.MinPeakHeightEditFieldLabel = uilabel(app.PreliminaryPhaseMatchingPanel);
            app.MinPeakHeightEditFieldLabel.Text = 'MinPeakHeight';
            app.MinPeakProminenceEditFieldLabel = uilabel(app.PreliminaryPhaseMatchingPanel);
            app.MinPeakProminenceEditFieldLabel.Text = 'MinPeakProminence';
            app.MinPeakProminence = uieditfield(app.PreliminaryPhaseMatchingPanel, 'numeric');
            app.MinPeakProminence.Value = 0.05;
            app.MinPeakHeight = uieditfield(app.PreliminaryPhaseMatchingPanel, 'numeric');
            app.NumScalesEditFieldLabel = uilabel(app.PreliminaryPhaseMatchingPanel);
            app.NumScalesEditFieldLabel.Text = 'NumScales';
            app.NumScales = uieditfield(app.PreliminaryPhaseMatchingPanel, 'numeric');
            app.NumScales.Limits = [1 Inf];
            app.NumScales.RoundFractionalValues = 'on';
            app.NumScales.Value = 5;
            app.RunPrelim = uibutton(app.PreliminaryPhaseMatchingPanel, 'push');
            app.RunPrelim.ButtonPushedFcn = createCallbackFcn(app, @RunPrelimPushed, true);
            app.RunPrelim.Text = 'Run';
            app.FindPeaks = uibutton(app.PreliminaryPhaseMatchingPanel, 'push');
            app.FindPeaks.ButtonPushedFcn = createCallbackFcn(app, @RunFindPeaks, true);
            app.FindPeaks.Text = 'FindPeaks';
            app.InputPanel = uipanel(app.UIFigure);
            app.InputPanel.Title = 'Input';
            app.DirectoryEditFieldLabel = uilabel(app.InputPanel);
            app.DirectoryEditFieldLabel.Text = 'Directory';
            app.Channel1Label = uilabel(app.InputPanel);
            app.Channel1Label.Text = 'Ch 2 (opt)';
            app.SetButton = uibutton(app.InputPanel, 'push');
            app.SetButton.ButtonPushedFcn = createCallbackFcn(app, @SetButtonPushed, true);
            app.SetButton.Text = 'Set';
            app.Channel2Label = uilabel(app.InputPanel);
            app.Channel2Label.Text = 'Ch 1';
            app.PhaseEditFieldLabel = uilabel(app.InputPanel);
            app.PhaseEditFieldLabel.Text = 'Phase';
            app.Phase = uieditfield(app.InputPanel, 'numeric');
            app.Phase.Limits = [1 100];
            app.Phase.RoundFractionalValues = 'on';
            app.Phase.Value = 1;
            app.MainChSpinnerLabel = uilabel(app.InputPanel);
            app.MainChSpinnerLabel.Text = 'Main Ch';
            app.MainCh = uispinner(app.InputPanel);
            app.MainCh.Limits = [1 3];
            app.MainCh.Value = 1;
            app.BrowseCh3 = uibutton(app.InputPanel, 'push');
            app.BrowseCh3.ButtonPushedFcn = createCallbackFcn(app, @BrowseCh3ButtonPushed, true);
            app.BrowseCh3.Text = 'Browse';
            app.Channel1Label_2 = uilabel(app.InputPanel);
            app.Channel1Label_2.Text = 'Ch 3 (opt)';
            app.FilenameCh3 = uieditfield(app.InputPanel, 'text');
            app.BrowseCh2 = uibutton(app.InputPanel, 'push');
            app.BrowseCh2.ButtonPushedFcn = createCallbackFcn(app, @BrowseCh2ButtonPushed, true);
            app.BrowseCh2.Text = 'Browse';
            app.FilenameCh2 = uieditfield(app.InputPanel, 'text');
            app.BrowseCh1 = uibutton(app.InputPanel, 'push');
            app.BrowseCh1.ButtonPushedFcn = createCallbackFcn(app, @BrowseCh1ButtonPushed, true);
            app.BrowseCh1.Text = 'Browse';
            app.FilenameCh1 = uieditfield(app.InputPanel, 'text');
            app.BrowseFolder = uibutton(app.InputPanel, 'push');
            app.BrowseFolder.ButtonPushedFcn = createCallbackFcn(app, @BrowseButtonPushed, true);
            app.BrowseFolder.Text = 'Browse';
            app.FolderPath = uieditfield(app.InputPanel, 'text');
            app.FolderPath.ValueChangedFcn = createCallbackFcn(app, @BrowseButtonPushed, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PhaseMatching

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end