classdef PhaseMatching_App_v2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        InputPanel                     matlab.ui.container.Panel
        FolderPath                     matlab.ui.control.EditField
        BrowseFolder                   matlab.ui.control.Button
        FilenameCh1                    matlab.ui.control.EditField
        BrowseCh1                      matlab.ui.control.Button
        FilenameCh2                    matlab.ui.control.EditField
        BrowseCh2                      matlab.ui.control.Button
        FilenameCh3                    matlab.ui.control.EditField
        Channel1Label_2                matlab.ui.control.Label
        BrowseCh3                      matlab.ui.control.Button
        MainCh                         matlab.ui.control.Spinner
        MainChSpinnerLabel             matlab.ui.control.Label
        Phase                          matlab.ui.control.NumericEditField
        PhaseEditFieldLabel            matlab.ui.control.Label
        Channel2Label                  matlab.ui.control.Label
        SetButton                      matlab.ui.control.Button
        Channel1Label                  matlab.ui.control.Label
        DirectoryEditFieldLabel        matlab.ui.control.Label
        PreliminaryPhaseMatchingPanel  matlab.ui.container.Panel
        FindPeaks                      matlab.ui.control.Button
        RunPrelim                      matlab.ui.control.Button
        NumScales                      matlab.ui.control.NumericEditField
        NumScalesEditFieldLabel        matlab.ui.control.Label
        MinPeakHeight                  matlab.ui.control.NumericEditField
        MinPeakProminence              matlab.ui.control.NumericEditField
        MinPeakProminenceEditFieldLabel  matlab.ui.control.Label
        MinPeakHeightEditFieldLabel    matlab.ui.control.Label
        EnhancedPhaseMatchingPanel     matlab.ui.container.ButtonGroup
        CheckNeighboursEditFieldLabel  matlab.ui.control.Label
        TemporalCorrect                matlab.ui.control.RadioButton
        SpatialCorrect                 matlab.ui.control.RadioButton
        HybridCorrect                  matlab.ui.control.RadioButton
        Padding                        matlab.ui.control.NumericEditField
        CheckNeighbours                matlab.ui.control.NumericEditField
        Threshold                      matlab.ui.control.NumericEditField
        ThresholdLabel                 matlab.ui.control.Label
        PaddingEditFieldLabel          matlab.ui.control.Label
        RunROIMatch                    matlab.ui.control.Button
        OutputPanel                    matlab.ui.container.ButtonGroup
        Session                        matlab.ui.control.RadioButton
        OutputName                     matlab.ui.control.EditField
        OutputNameLabel                matlab.ui.control.Label
        MultiTiff                      matlab.ui.control.RadioButton
        PhaseOme                       matlab.ui.control.RadioButton
        SingleOme                      matlab.ui.control.RadioButton
        SaveButton                     matlab.ui.control.Button
    end

    properties (Access = private)
        InputData       % class containing input dataset and associated metadata
        InputParams     % class containing input variables and associated metadata
        SsimScoresMain  % preliminary ssim scores

        FilePaths       % structure containing full pathnames of input files
        N_channels      % number of channels we are phase matching
        MainPath        % main channel used for phase matching
        TifInfo         % metainformation about input data
        ImgRef          % reference image used for phase matching
        N_pks           % number of phase matched frames
        SsimScores      % similarity scores
        N_Frames        % number of frames in dataset
        ImagesToSave    % array of phased matched images ready to be saved
        MeanDist        % average distance between peaks
        ROI             % region of interest we want to narrow in phase matching
        PkLocs          % location of peaks
        CutLength       % how many frames to include in a cardiac cycle
        PeakOffset      % offset from original similarity peak 
        DialogApp       % Dialog box app
    end

    methods (Access = public)     
        function UpdateOffset(app, selected)
            app.PeakOffset = selected;
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Callback function: BrowseFolder, FolderPath
        function BrowseButtonPushed(app, event)
            app.FolderPath.Value = strcat(string(uigetdir),'\');
        end

        % Button pushed function: BrowseCh1
        function BrowseCh1ButtonPushed(app, event)
             app.FilenameCh1.Value = string( ...
                uigetfile({'*.tif','*.ome'}, ...
                'Select a File', app.FolderPath.Value) ...
                );
        end

        % Button pushed function: BrowseCh2
        function BrowseCh2ButtonPushed(app, event)
            app.FilenameCh2.Value = string( ...
                uigetfile({'*.tif','*.ome'}, ...
                'Select a File', app.FolderPath.Value) ...
                );
        end

        % Button pushed function: BrowseCh3
        function BrowseCh3ButtonPushed(app, event)
            app.FilenameCh3.Value = string( ...
                uigetfile({'*.tif','*.ome'}, ...
                'Select a File', app.FolderPath.Value) ...
                );
        end

        % Button pushed function: SetButton
        function SetButtonPushed(app, event)
            if exist('msg', 'var')
                delete(msg);
                clear('msg');
            end
            msg = msgbox('Processing user inputs');
            
            app.InputData = input_data;
            app.InputData.folder_path = app.FolderPath.Value;
            app.InputData.filename_1 = app.FilenameCh1.Value;
            app.InputData.filename_2 = app.FilenameCh2.Value;
            app.InputData.filename_3 = app.FilenameCh3.Value;
            app.InputData.main_ch = app.MainCh.Value;
            app.InputData.phase = app.Phase.Value;
            
            close all;
            figure(1);
            imshow(app.InputData.get_img);
            title('This is our reference image for phase matching')

            if exist('msg', 'var')
                delete(msg);
                clear('msg');
            end
            msg = msgbox('User inputs processed');
        end

        % Button pushed function: RunPrelim
        function RunPrelimPushed(app, event)
            if exist('msg', 'var')
                delete(msg);
                clear('msg');
            end
            msg = msgbox('Performing preliminary phase matching');
            
            app.InputParams = input_params;
            app.InputParams.n_scales = app.NumScales.Value;
            app.SsimScoresMain = ssim_wrapper(app.InputData.get_img, app.InputData.main_path, ...
                1:app.InputData.width, 1:app.InputData.height, 1:app.InputData.n_frames, ...
                app.InputParams.n_scales);
            
            close all;
            figure(2);
            plot(1:app.InputData.n_frames, app.SsimScoresMain);
            title('Similarity scores for each frame')

            if exist('msg', 'var')
                delete(msg);
                clear('msg');
            end
            msgbox('Preliminary phase matching complete')
        end

        % Button pushed function: FindPeaks
        function RunFindPeaks(app, event)
            if exist('msg', 'var')
                delete(msg);
                clear('msg');
            end
            msg = msgbox('Determining location of peaks.');
            
            app.InputParams.min_peak_height = app.MinPeakHeight.Value;
            app.InputParams.min_peak_prominence = app.MinPeakProminence.Value;
            [Pks, app.PkLocs, app.N_pks, app.MeanDist] = find_peaks(app.SsimScoresMain, ...
                app.InputParams.min_peak_height, app.InputParams.min_peak_prominence, app.InputData.phase);
            
            close all;
            figure(2);
            plot(1:app.InputData.n_frames, app.SsimScoresMain); hold on;
            plot(app.PkLocs, Pks, "*");
            title(['Similarity scores for each frame. ', num2str(app.N_pks), ' peaks found at an average distance of ', num2str(app.MeanDist)])

            app.InputParams.padding = app.Padding.Value;
            app.CutLength = app.InputParams.cut_length(app.MeanDist);
            app.ImagesToSave = cell(app.InputData.n_channels, app.N_pks, 2*app.CutLength+1);
            app.ImagesToSave = construct_movie(app.InputData.file_paths, app.ImagesToSave, app.PkLocs, app.CutLength);

            if exist('msg', 'var')
                delete(msg);
                clear('msg');
            end
            msg = msgbox('Peaks located.');
        end

        % Button pushed function: RunROIMatch
        function RunROIGoodPeaksPushed(app, event)
            if exist('msg', 'var')
                delete(msg);
                clear('msg');
            end
            msg = msgbox('Performing advanced phase matching.');

            % Initialisation - cut images into one cardiac cycle + n frames
            app.InputParams.padding = app.Padding.Value;
            app.InputParams.n_neighbours = app.CheckNeighbours.Value;

            % Show image for user to pick region of interest
            figure(3);
            imshow(app.InputData.get_img);
            title('Please draw a ROI to focus on here.')
            rect = drawrectangle;
            app.InputParams.ROI = rect.Position;

            % Perform corrections depending on mode
            switch app.EnhancedPhaseMatchingPanel.SelectedObject.Tag
                case 'TemporalCorrect'
                    [Pks, MatchedFrames, app.N_pks, app.MeanDist, SsimsTotal] = temporal_phase_matching( ...
                        app.InputData, app.InputParams, app.MeanDist);

                case 'SpatialCorrect'
                    [MatchedFrames, app.MeanDist] = best_neighbour_phase_matching(app.InputData, app.InputParams, app.PkLocs);

                case 'HybridCorrect'
                    MatchedFrames(1) = app.PkLocs(1);
                    for i_pk = 1:length(app.PkLocs)
                        if i_pk ~= 1
                            frame_idx = app.PkLocs(i_pk)-app.InputParams.n_neighbours : app.PkLocs(i_pk)+app.InputParams.n_neighbours;
                            ssim_vals = ssim_wrapper(app.InputData.get_img, app.InputData.main_path, app.InputParams.x_bounds, app.InputParams.y_bounds, frame_idx, app.InputParams.n_scales);
                            [max_ssim, max_ssim_loc] = max(ssim_vals);
                            max_idx = app.PkLocs(i_pk) + max_ssim_loc - app.InputParams.n_neighbours - 1;
                            ssim_vals(max_ssim_loc) = 0; % set max to zero so we can find second highest
                            [max_2_ssim, max_2_ssim_loc] = max(ssim_vals);
                            max_2_idx = app.PkLocs(i_pk) + max_2_ssim_loc - app.InputParams.n_neighbours - 1;
                            if (max_ssim - max_2_ssim)/max_ssim < app.Threshold.Value/100 % If close call, get user to select. Add tolerance option???
                                app.DialogApp = ManualCorrect( ...
                                    app, app.InputData.main_path, i_pk, ...
                                    max_idx, max_2_idx);
                                % expected number of peaks too???
                                uiwait;
                                MatchedFrames(i_pk) = app.PeakOffset;
                            else
                                MatchedFrames(i_pk) = app.PkLocs(i_pk);
                            end
                        end
                    end
                    app.MeanDist = mean(diff(MatchedFrames));
            end

            app.CutLength = app.InputParams.cut_length(app.MeanDist);
            app.ImagesToSave = cell(app.InputData.n_channels, app.N_pks, 2*app.CutLength+1);
            app.ImagesToSave = construct_movie(app.InputData.file_paths, app.ImagesToSave, MatchedFrames, app.CutLength);
            
            if exist('msg', 'var')
                delete(msg);
                clear('msg');
            end
            close all;
            msg = msgbox('Phase matching complete. Select save option.');
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            if exist('msg', 'var')
                delete(msg);
                clear('msg');
            end
            msg = msgbox('Saving files');

            OutputPath = [app.FolderPath.Value, app.OutputName.Value];
            mkdir(OutputPath);
            javaaddpath('loci_tools.jar')

            switch app.OutputPanel.SelectedObject.Tag
                case 'MultiTiff'
                    save_multitiff(app.InputData, app.CutLength, app.ImagesToSave, app.N_pks, OutputPath);
                    if exist('msg', 'var')
                        delete(msg);
                        clear('msg');
                    end
                    msgbox('Files saved');

                case 'PhaseOme'
                    ImageFile=['SinglePhase_z' padnumber(3,num2str(app.InputData.phase)) '.ome'];
                    if isfile([OutputPath,filesep,ImageFile])
                        if exist('msg', 'var')
                            delete(msg);
                            clear('msg');
                        end
                        msg = msgbox('File already exists. Overwrite prevented. Consider renaming original file.');
                    else
                        save_single_phase(app.InputData, app.CutLength, app.ImagesToSave, app.N_pks, OutputPath);
                        if exist('msg', 'var')
                            delete(msg);
                            clear('msg');
                        end
                        msgbox('Files saved');
                    end

                case 'SingleOme'
                    ImageFile = ['AllPhase.ome'];
                    if isfile([OutputPath,filesep,ImageFile])
                        if exist('msg', 'var')
                            delete(msg);
                            clear('msg');
                        end
                        msg = msgbox('File already exists. Overwrite prevented. Consider renaming original file');
                    else
                        save_all_phase(app.InputData, app.CutLength, app.ImagesToSave, app.N_pks, OutputPath);
                        if exist('msg', 'var')
                            delete(msg);
                            clear('msg');
                        end
                        msgbox('Files saved');
                    end

                case 'Session'
                    save_phase_matching(app.FolderPath.Value, app.FilenameCh1.Value, ...
                        app.FilenameCh2.Value, app.FilenameCh3.Value, app.MainCh.Value, ...
                        app.Phase.Value, app.NumScales.Value, app.MinPeakHeight.Value, ...
                        app.MinPeakProminence.Value, app.ROI, app.Padding.Value, app.CheckNeighbours.Value, app.OutputName.Value)
                    if exist('msg', 'var')
                        delete(msg);
                        clear('msg');
                    end
                    msgbox('Files saved');
            end

%             if exist('msg', 'var')
%                 delete(msg);
%                 clear('msg');
%             end
%             msgbox('Files saved');
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app.DialogApp)
            close all;
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.OutputPanel = uibuttongroup(app.UIFigure);
            app.OutputPanel.Title = 'Output';
            app.SaveButton = uibutton(app.OutputPanel, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.SingleOme = uiradiobutton(app.OutputPanel);
            app.SingleOme.Tag = 'SingleOme';
            app.SingleOme.Text = 'Save all';
            app.PhaseOme = uiradiobutton(app.OutputPanel);
            app.PhaseOme.Tag = 'PhaseOme';
            app.PhaseOme.Text = 'Save single phase';
            app.PhaseOme.Value = true;
            app.MultiTiff = uiradiobutton(app.OutputPanel);
            app.MultiTiff.Tag = 'MultiTiff';
            app.MultiTiff.Text = 'Save as individual tiffs';
            app.OutputNameLabel = uilabel(app.OutputPanel);
            app.OutputNameLabel.Text = 'Output Folder';
            app.OutputName = uieditfield(app.OutputPanel, 'text');
            app.OutputName.Value = 'PhaseMatchingOutput';
            app.Session = uiradiobutton(app.OutputPanel);
            app.Session.Tag = 'Session';
            app.Session.Text = 'Save session settings';
            app.EnhancedPhaseMatchingPanel = uibuttongroup(app.UIFigure);
            app.EnhancedPhaseMatchingPanel.Title = 'ROI Based Phase Matching';
            app.RunROIMatch = uibutton(app.EnhancedPhaseMatchingPanel, 'push');
            app.RunROIMatch.ButtonPushedFcn = createCallbackFcn(app, @RunROIGoodPeaksPushed, true);
            app.RunROIMatch.Text = 'Run';
            app.PaddingEditFieldLabel = uilabel(app.EnhancedPhaseMatchingPanel);
            app.PaddingEditFieldLabel.Text = 'Padding';
            app.ThresholdLabel = uilabel(app.EnhancedPhaseMatchingPanel);
            app.ThresholdLabel.Text = 'Threshold %';
            app.Threshold = uieditfield(app.EnhancedPhaseMatchingPanel, 'numeric');
            app.Threshold.Limits = [0 100];
            app.Threshold.Value = 5;
            app.CheckNeighbours = uieditfield(app.EnhancedPhaseMatchingPanel, 'numeric');
            app.CheckNeighbours.Limits = [1 Inf];
            app.CheckNeighbours.Value = 2;
            app.Padding = uieditfield(app.EnhancedPhaseMatchingPanel, 'numeric');
            app.Padding.Limits = [0 Inf];
            app.Padding.RoundFractionalValues = 'on';
            app.Padding.Value = 3;
            app.HybridCorrect = uiradiobutton(app.EnhancedPhaseMatchingPanel);
            app.HybridCorrect.Tag = 'HybridCorrect';
            app.HybridCorrect.Text = 'Hybrid correction (may require extensive user input)';
            app.SpatialCorrect = uiradiobutton(app.EnhancedPhaseMatchingPanel);
            app.SpatialCorrect.Tag = 'SpatialCorrect';
            app.SpatialCorrect.Text = 'Spatial correction';
            app.TemporalCorrect = uiradiobutton(app.EnhancedPhaseMatchingPanel);
            app.TemporalCorrect.Tag = 'TemporalCorrect';
            app.TemporalCorrect.Text = 'Temporal correction (recommended)';
            app.TemporalCorrect.Value = true;
            app.CheckNeighboursEditFieldLabel = uilabel(app.EnhancedPhaseMatchingPanel);
            app.CheckNeighboursEditFieldLabel.Text = 'Check Neighbours';
            app.PreliminaryPhaseMatchingPanel = uipanel(app.UIFigure);
            app.PreliminaryPhaseMatchingPanel.Title = 'Preliminary Phase Matching';
            app.MinPeakHeightEditFieldLabel = uilabel(app.PreliminaryPhaseMatchingPanel);
            app.MinPeakHeightEditFieldLabel.Text = 'MinPeakHeight';
            app.MinPeakProminenceEditFieldLabel = uilabel(app.PreliminaryPhaseMatchingPanel);
            app.MinPeakProminenceEditFieldLabel.Text = 'MinPeakProminence';
            app.MinPeakProminence = uieditfield(app.PreliminaryPhaseMatchingPanel, 'numeric');
            app.MinPeakProminence.Value = 0.05;
            app.MinPeakHeight = uieditfield(app.PreliminaryPhaseMatchingPanel, 'numeric');
            app.NumScalesEditFieldLabel = uilabel(app.PreliminaryPhaseMatchingPanel);
            app.NumScalesEditFieldLabel.Text = 'NumScales';
            app.NumScales = uieditfield(app.PreliminaryPhaseMatchingPanel, 'numeric');
            app.NumScales.Limits = [1 Inf];
            app.NumScales.RoundFractionalValues = 'on';
            app.NumScales.Value = 5;
            app.RunPrelim = uibutton(app.PreliminaryPhaseMatchingPanel, 'push');
            app.RunPrelim.ButtonPushedFcn = createCallbackFcn(app, @RunPrelimPushed, true);
            app.RunPrelim.Text = 'Run';
            app.FindPeaks = uibutton(app.PreliminaryPhaseMatchingPanel, 'push');
            app.FindPeaks.ButtonPushedFcn = createCallbackFcn(app, @RunFindPeaks, true);
            app.FindPeaks.Text = 'FindPeaks';
            app.InputPanel = uipanel(app.UIFigure);
            app.InputPanel.Title = 'Input';
            app.DirectoryEditFieldLabel = uilabel(app.InputPanel);
            app.DirectoryEditFieldLabel.Text = 'Directory';
            app.Channel1Label = uilabel(app.InputPanel);
            app.Channel1Label.Text = 'Ch 2 (opt)';
            app.SetButton = uibutton(app.InputPanel, 'push');
            app.SetButton.ButtonPushedFcn = createCallbackFcn(app, @SetButtonPushed, true);
            app.SetButton.Text = 'Set';
            app.Channel2Label = uilabel(app.InputPanel);
            app.Channel2Label.Text = 'Ch 1';
            app.PhaseEditFieldLabel = uilabel(app.InputPanel);
            app.PhaseEditFieldLabel.Text = 'Phase';
            app.Phase = uieditfield(app.InputPanel, 'numeric');
            app.Phase.Limits = [1 100];
            app.Phase.RoundFractionalValues = 'on';
            app.Phase.Value = 1;
            app.MainChSpinnerLabel = uilabel(app.InputPanel);
            app.MainChSpinnerLabel.Text = 'Main Ch';
            app.MainCh = uispinner(app.InputPanel);
            app.MainCh.Limits = [1 3];
            app.MainCh.Value = 1;
            app.BrowseCh3 = uibutton(app.InputPanel, 'push');
            app.BrowseCh3.ButtonPushedFcn = createCallbackFcn(app, @BrowseCh3ButtonPushed, true);
            app.BrowseCh3.Text = 'Browse';
            app.Channel1Label_2 = uilabel(app.InputPanel);
            app.Channel1Label_2.Text = 'Ch 3 (opt)';
            app.FilenameCh3 = uieditfield(app.InputPanel, 'text');
            app.BrowseCh2 = uibutton(app.InputPanel, 'push');
            app.BrowseCh2.ButtonPushedFcn = createCallbackFcn(app, @BrowseCh2ButtonPushed, true);
            app.BrowseCh2.Text = 'Browse';
            app.FilenameCh2 = uieditfield(app.InputPanel, 'text');
            app.BrowseCh1 = uibutton(app.InputPanel, 'push');
            app.BrowseCh1.ButtonPushedFcn = createCallbackFcn(app, @BrowseCh1ButtonPushed, true);
            app.BrowseCh1.Text = 'Browse';
            app.FilenameCh1 = uieditfield(app.InputPanel, 'text');
            app.BrowseFolder = uibutton(app.InputPanel, 'push');
            app.BrowseFolder.ButtonPushedFcn = createCallbackFcn(app, @BrowseButtonPushed, true);
            app.BrowseFolder.Text = 'Browse';
            app.FolderPath = uieditfield(app.InputPanel, 'text');
            app.FolderPath.ValueChangedFcn = createCallbackFcn(app, @BrowseButtonPushed, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PhaseMatching_App_v2

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end