classdef SignalAnalysis < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        InputPanel                matlab.ui.container.Panel
        EndFrameLabel             matlab.ui.control.Label
        StartFrameLabel           matlab.ui.control.Label
        FolderPath                matlab.ui.control.EditField
        BrowseFolder              matlab.ui.control.Button
        FilenameNucCh             matlab.ui.control.EditField
        BrowseNucCh               matlab.ui.control.Button
        FilenameCaCh              matlab.ui.control.EditField
        BrowseCaCh                matlab.ui.control.Button
        Channel2Label             matlab.ui.control.Label
        StartFrame                matlab.ui.control.NumericEditField
        EndFrame                  matlab.ui.control.NumericEditField
        SetInputs                 matlab.ui.control.Button
        Channel1Label             matlab.ui.control.Label
        DirectoryEditFieldLabel   matlab.ui.control.Label
        SignalAnalysisToolsPanel  matlab.ui.container.Panel
        Overlay                   matlab.ui.control.Button
        SelectROI                 matlab.ui.control.Button
        ExposureTime              matlab.ui.control.NumericEditField
        MeanDistance              matlab.ui.control.NumericEditField
        PlotRawSignal             matlab.ui.control.Button
        MeanDistLabel             matlab.ui.control.Label
        PlotNormalisedSignal      matlab.ui.control.Button
        MakeGraphMovie            matlab.ui.control.Button
        SamplingRateLabel         matlab.ui.control.Label
        OutputPanel               matlab.ui.container.ButtonGroup
        OutputFolder              matlab.ui.control.EditField
        OutputNameLabel           matlab.ui.control.Label
        SaveButton                matlab.ui.control.Button
        Session                   matlab.ui.control.RadioButton
    end

    properties (Access = private)
        FilePaths           % structure containing full pathnames of input files
        OutputPath          % output file locations
        TifInfo             % information about data
        N_frames            % number of frames in movie
        N_channels          % number of channels we have inputted data for
        OverlayedImages     % images to save structure
        Signals             % 
        NormSignalByBackground
        binaryImage
        
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Callback function: BrowseFolder, FolderPath
        function BrowseButtonPushed(app, event)
            app.FolderPath.Value = strcat(string(uigetdir),'\');
        end

        % Button pushed function: BrowseNucCh
        function BrowseNucChButtonPushed(app, event)
             app.FilenameNucCh.Value = string( ...
                uigetfile({'*.tif','*.ome'}, ...
                'Select a File', app.FolderPath.Value) ...
                );
        end

        % Button pushed function: BrowseCaCh
        function BrowseCaChButtonPushed(app, event)
            app.FilenameCaCh.Value = string( ...
                uigetfile({'*.tif','*.ome'}, ...
                'Select a File', app.FolderPath.Value) ...
                );
        end

        % Button pushed function: SetInputs
        function SetInputsPushed(app, event)
            if exist('msg', 'var')
                delete(msg);
                clear('msg');
            end
            msg = msgbox('Processing user inputs');

            [app.FilePaths, app.OutputPath, app.TifInfo, app.N_frames, app.N_channels, app.EndFrame.Value] = initialise( ...
                app.FolderPath.Value, app.FilenameNucCh.Value, app.FilenameCaCh.Value, ...
                app.StartFrame.Value, app.EndFrame.Value, app.OutputFolder.Value);

            % Display for visual confirmation
            figure(1);
            subplot(1,2,1)
            imshow(imread(app.FilePaths{1}, app.StartFrame.Value));
            title('First frame')
            subplot(1,2,2);
            imshow(imread(app.FilePaths{1}, app.EndFrame.Value));
            title('Last frame');

            if exist('msg', 'var')
                delete(msg);
                clear('msg');
            end
            msg = msgbox('User inputs processed'); 
        end

        % Button pushed function: Overlay
        function OverlayPushed(app, event)
            if exist('msg', 'var')
                delete(msg);
                clear('msg');
            end
            msg = msgbox('Overlaying movie frames');

            OverlayedNuc = overlay(app.FilePaths{1}, app.TifInfo, app.StartFrame.Value, app.EndFrame.Value, app.N_frames);
            imwrite(OverlayedNuc, [app.OutputPath, filesep, 'OverlayedNuc.tif'], 'tif')
            OverlayedCa = overlay(app.FilePaths{2}, app.TifInfo, app.StartFrame.Value, app.EndFrame.Value, app.N_frames);
            imwrite(OverlayedCa, [app.OutputPath, filesep, 'OverlayedCa.tif'], 'tif')
            app.OverlayedImages = {OverlayedNuc, OverlayedCa};

            % Display for visual confirmation
            figure(2);
            subplot(1,2,1)
            imshow(OverlayedNuc);
            title('Overlayed Nuclear Channel')
            subplot(1,2,2);
            imshow(OverlayedCa);
            title('Overlayed Calcium Channel'); 

            if exist('msg', 'var')
                delete(msg);
                clear('msg');
            end
            msgbox('Overlay complete')
        end

        % Button pushed function: SelectROI
        function SelectROIButtonPushed(app, event)
            NucOverlay = mat2gray(app.OverlayedImages{1});
            CaOverlay = mat2gray(app.OverlayedImages{2});
            fusedChannel = imfuse(CaOverlay, NucOverlay);
            figure();
            imshow(fusedChannel)
            axis on;
            title('Do not close this figure window till end of script!', 'FontSize', 16);
            message = sprintf('Left click and hold to begin drawing.\nSimply lift the mouse button to finish');
            uiwait(msgbox(message));
            hFH = imfreehand();
            app.binaryImage = hFH.createMask();
            ROI_boundary = app.binaryImage;
            save("ROI_boundary.mat","ROI_boundary")
            close();
            save_ROI(fusedChannel, app.binaryImage, app.OutputPath)

            if exist('msg', 'var')
                delete(msg);
                clear('msg');
            end
            msgbox('Region of interest saved')
        end

        % Button pushed function: PlotRawSignal
        function PlotRawSignalButtonPushed(app, event)
            if exist('msg', 'var')
                delete(msg);
                clear('msg');
            end
            msg = msgbox('Calculating and plotting raw signal');

            SignalNuc = calculate_signal(app.StartFrame.Value,app.EndFrame.Value,app.FilePaths{1},app.binaryImage);
            SignalCa = calculate_signal(app.StartFrame.Value,app.EndFrame.Value,app.FilePaths{2},app.binaryImage);
            app.Signals = {SignalNuc, SignalCa};

            figure();
            hold on
            plot(0:1:(app.N_frames-1)*1, SignalCa, 'g');
            plot(0:1:(app.N_frames-1)*1, SignalNuc, 'm');
            title('Raw signal - cycles')
            xlabel('Time [cycles]');
            ylabel('Signal Intensity');
            legend('CaSignal','NucSignal')
            
            figure();
            hold on
            frame_scale = app.MeanDistance.Value; % plot according to number of actual frames
            plot(0:frame_scale:(app.N_frames-1)*frame_scale, SignalCa, 'g');
            plot(0:frame_scale:(app.N_frames-1)*frame_scale, SignalNuc, 'm');
            title('Raw signal - frames')
            xlabel('Time [frames]');
            ylabel('Signal Intensity');
            legend('CaSignal','NucSignal')
            
            figure();
            hold on
            time_scale = app.MeanDistance.Value*app.ExposureTime.Value/1000; % plot according to real time
            plot(0:time_scale:(app.N_frames-1)*time_scale, SignalCa, 'g');
            plot(0:time_scale:(app.N_frames-1)*time_scale, SignalNuc, 'm');
            title('Raw signal - time')
            xlabel('Time [s]');
            ylabel('Signal Intensity');
            legend('CaSignal','NucSignal')

            if exist('msg', 'var')
                delete(msg);
                clear('msg');
            end
            msg = msgbox('Raw signal plotted');
        end

        % Button pushed function: PlotNormalisedSignal
        function PlotNormalisedSignalButtonPushed(app, event)
            if exist('msg', 'var')
                delete(msg);
                clear('msg');
            end
            msg = msgbox('Calculating and plotting normalised signal');

            SignalNuc = app.Signals{1};
            SignalCa = app.Signals{2};
            app.Signals = {SignalNuc, SignalCa};
            Baseline = min(SignalCa./SignalNuc);
            app.NormSignalByBackground = (SignalCa./SignalNuc - Baseline)/(Baseline);

            figure();
            hold on
            plot(0:1:(app.N_frames-1)*1, app.NormSignalByBackground, 'k');
            title('Normalised calcium signal - cycles')
            xlabel('Time [cycles]');
            ylabel('Signal Intensity');
            
            figure();
            hold on
            frame_scale = app.MeanDistance.Value; % plot according to number of actual frames
            plot(0:frame_scale:(app.N_frames-1)*frame_scale, app.NormSignalByBackground, 'k');
            title('Normalised calcium signal - frames')
            xlabel('Time [frames]');
            ylabel('Signal Intensity');
            
            figure();
            hold on
            time_scale = app.MeanDistance.Value*app.ExposureTime.Value/1000; % plot according to real time
            plot(0:time_scale:(app.N_frames-1)*time_scale, app.NormSignalByBackground, 'k');
            title('Normalised calcium signal - time')
            xlabel('Time [s]');
            ylabel('Signal Intensity');

            if exist('msg', 'var')
                delete(msg);
                clear('msg');
            end
            msg = msgbox('Normalised signal plotted');
        end

        % Button pushed function: MakeGraphMovie
        function MakeGraphMovieButtonPushed(app, event)
            if exist('msg', 'var')
                delete(msg);
                clear('msg');
            end
            msg = msgbox('Creating graph movie');
            
            GraphMoviePath = [app.OutputPath,'\GraphMovie'];
            mkdir(GraphMoviePath);
            
            for i = 1:app.N_frames
                figure('Visible','off')
                time_scale = app.MeanDistance.Value*app.ExposureTime.Value/1000; % plot according to real time
                plot(0:time_scale:(i-1)*time_scale, app.NormSignalByBackground(1:i), 'k');
                title('Normalised Calcium Signal')
                xlabel('Time [s]');
                ylabel('Signal Intensity');
                axis([0,app.N_frames*time_scale,0,max(app.NormSignalByBackground)]);
                iName = string(i);
                imageName = fullfile(GraphMoviePath,iName);
                saveas(gcf,imageName,'tiff');
            end
            close all;

            if exist('msg', 'var')
                delete(msg);
                clear('msg');
            end
            msg = msgbox('Graph movie created');
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            close all;
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.OutputPanel = uibuttongroup(app.UIFigure);
            app.OutputPanel.Title = 'Output';
            app.Session = uiradiobutton(app.OutputPanel);
            app.Session.Tag = 'Session';
            app.Session.Text = 'Save session settings';
            app.Session.Value = true;
            app.SaveButton = uibutton(app.OutputPanel, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.OutputNameLabel = uilabel(app.OutputPanel);
            app.OutputNameLabel.Text = 'Output Folder';
            app.OutputFolder = uieditfield(app.OutputPanel, 'text');
            app.OutputFolder.Value = 'CaSignalVsTime';
            app.SignalAnalysisToolsPanel = uipanel(app.UIFigure);
            app.SignalAnalysisToolsPanel.Title = 'Signal Analysis Tools';
            app.SamplingRateLabel = uilabel(app.SignalAnalysisToolsPanel);
            app.SamplingRateLabel.Text = 'Exposure Time [ms]';
            app.MakeGraphMovie = uibutton(app.SignalAnalysisToolsPanel, 'push');
            app.MakeGraphMovie.ButtonPushedFcn = createCallbackFcn(app, @MakeGraphMovieButtonPushed, true);
            app.MakeGraphMovie.Text = 'Make Graph Movie';
            app.PlotNormalisedSignal = uibutton(app.SignalAnalysisToolsPanel, 'push');
            app.PlotNormalisedSignal.ButtonPushedFcn = createCallbackFcn(app, @PlotNormalisedSignalButtonPushed, true);
            app.PlotNormalisedSignal.Text = 'Plot Normalised Calcium Level';
            app.MeanDistLabel = uilabel(app.SignalAnalysisToolsPanel);
            app.MeanDistLabel.Text = 'Cycle Length [frames]';
            app.PlotRawSignal = uibutton(app.SignalAnalysisToolsPanel, 'push');
            app.PlotRawSignal.ButtonPushedFcn = createCallbackFcn(app, @PlotRawSignalButtonPushed, true);
            app.PlotRawSignal.Text = 'Plot Preliminary Signal';
            app.MeanDistance = uieditfield(app.SignalAnalysisToolsPanel, 'numeric');
            app.MeanDistance.Limits = [1 Inf];
            app.MeanDistance.Value = 13.5;
            app.ExposureTime = uieditfield(app.SignalAnalysisToolsPanel, 'numeric');
            app.ExposureTime.Limits = [0 Inf];
            app.ExposureTime.RoundFractionalValues = 'on';
            app.ExposureTime.Value = 24;
            app.SelectROI = uibutton(app.SignalAnalysisToolsPanel, 'push');
            app.SelectROI.ButtonPushedFcn = createCallbackFcn(app, @SelectROIButtonPushed, true);
            app.SelectROI.Text = 'Select Region of Interest';
            app.Overlay = uibutton(app.SignalAnalysisToolsPanel, 'push');
            app.Overlay.ButtonPushedFcn = createCallbackFcn(app, @OverlayPushed, true);
            app.Overlay.Text = 'Overlay';
            app.InputPanel = uipanel(app.UIFigure);
            app.InputPanel.Title = 'Input';
            app.DirectoryEditFieldLabel = uilabel(app.InputPanel);
            app.DirectoryEditFieldLabel.Text = 'Directory';
            app.Channel1Label = uilabel(app.InputPanel);
            app.Channel1Label.Text = 'Ca Ch';
            app.SetInputs = uibutton(app.InputPanel, 'push');
            app.SetInputs.ButtonPushedFcn = createCallbackFcn(app, @SetInputsPushed, true);
            app.SetInputs.Text = 'Set';
            app.EndFrame = uieditfield(app.InputPanel, 'numeric');
            app.StartFrame = uieditfield(app.InputPanel, 'numeric');
            app.StartFrame.Limits = [0 Inf];
            app.StartFrame.RoundFractionalValues = 'on';
            app.StartFrame.Value = 1;
            app.Channel2Label = uilabel(app.InputPanel);
            app.Channel2Label.Text = 'Nuc Ch';
            app.BrowseCaCh = uibutton(app.InputPanel, 'push');
            app.BrowseCaCh.ButtonPushedFcn = createCallbackFcn(app, @BrowseCaChButtonPushed, true);
            app.BrowseCaCh.Text = 'Browse';
            app.FilenameCaCh = uieditfield(app.InputPanel, 'text');
            app.BrowseNucCh = uibutton(app.InputPanel, 'push');
            app.BrowseNucCh.ButtonPushedFcn = createCallbackFcn(app, @BrowseNucChButtonPushed, true);
            app.BrowseNucCh.Text = 'Browse';
            app.FilenameNucCh = uieditfield(app.InputPanel, 'text');
            app.BrowseFolder = uibutton(app.InputPanel, 'push');
            app.BrowseFolder.ButtonPushedFcn = createCallbackFcn(app, @BrowseButtonPushed, true);
            app.BrowseFolder.Text = 'Browse';
            app.FolderPath = uieditfield(app.InputPanel, 'text');
            app.FolderPath.ValueChangedFcn = createCallbackFcn(app, @BrowseButtonPushed, true);
            app.StartFrameLabel = uilabel(app.InputPanel);
            app.StartFrameLabel.Text = 'Start Frame';
            app.EndFrameLabel = uilabel(app.InputPanel);
            app.EndFrameLabel.Text = 'End Frame';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SignalAnalysis

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end