classdef SignalAnalysis_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        SignalAnalysis           matlab.ui.container.ButtonGroup
        PlotNormalisedSignal     matlab.ui.control.Button
        MakeGraphMovie           matlab.ui.control.Button
        PlotRawSignal            matlab.ui.control.Button
        MeanDistance             matlab.ui.control.NumericEditField
        MeanDistLabel            matlab.ui.control.Label
        ExposureTime             matlab.ui.control.NumericEditField
        SamplingRateLabel        matlab.ui.control.Label
        CalciumNormalise         matlab.ui.control.RadioButton
        ReferenceNormalise       matlab.ui.control.RadioButton
        OverlayPanel             matlab.ui.container.ButtonGroup
        SelectROI                matlab.ui.control.Button
        Overlay                  matlab.ui.control.Button
        ReferencechannelButton   matlab.ui.control.RadioButton
        CalciumchannelButton     matlab.ui.control.RadioButton
        BothchannelsButton       matlab.ui.control.RadioButton
        InputPanel               matlab.ui.container.Panel
        EndFrameLabel            matlab.ui.control.Label
        StartFrameLabel          matlab.ui.control.Label
        FolderPath               matlab.ui.control.EditField
        BrowseFolder             matlab.ui.control.Button
        FilenameRefCh            matlab.ui.control.EditField
        BrowseRefCh              matlab.ui.control.Button
        FilenameCaCh             matlab.ui.control.EditField
        BrowseCaCh               matlab.ui.control.Button
        Channel2Label            matlab.ui.control.Label
        StartFrame               matlab.ui.control.NumericEditField
        EndFrame                 matlab.ui.control.NumericEditField
        SetInputs                matlab.ui.control.Button
        Channel1Label            matlab.ui.control.Label
        DirectoryEditFieldLabel  matlab.ui.control.Label
        OutputPanel              matlab.ui.container.ButtonGroup
        OutputFolder             matlab.ui.control.EditField
        OutputNameLabel          matlab.ui.control.Label
        SaveButton               matlab.ui.control.Button
        Session                  matlab.ui.control.RadioButton
    end

    properties (Access = private)
        FilePaths               % structure containing full pathnames of input files
        OutputPath              % output file location
        TifInfo                 % metainformation about input data
        N_frames                % number of frames in movie
        N_channels              % number of channels we have inputted data for
        OverlayedImages         % images to save structure
        Signals                 % structure containing intensity signals
        NormSignal              % normalised calcium signal to background
        BinaryImage             % binary array of ROI boundary
        
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Callback function: BrowseFolder, FolderPath
        function BrowseButtonPushed(app, event)
            app.FolderPath.Value = strcat(string(uigetdir),'\');
        end

        % Button pushed function: BrowseRefCh
        function BrowseRefChButtonPushed(app, event)
             app.FilenameRefCh.Value = string( ...
                uigetfile({'*.tif','*.ome'}, ...
                'Select a File', app.FolderPath.Value) ...
                );
        end

        % Button pushed function: BrowseCaCh
        function BrowseCaChButtonPushed(app, event)
            app.FilenameCaCh.Value = string( ...
                uigetfile({'*.tif','*.ome'}, ...
                'Select a File', app.FolderPath.Value) ...
                );
        end

        % Button pushed function: SetInputs
        function SetInputsPushed(app, event)
            if exist('msg', 'var')
                delete(msg);
                clear('msg');
            end
            msg = msgbox('Processing user inputs');

            [app.FilePaths, app.OutputPath, app.TifInfo, app.N_frames, app.N_channels, app.EndFrame.Value] = initialise_signal_analysis( ...
                app.FolderPath.Value, app.FilenameRefCh.Value, app.FilenameCaCh.Value, ...
                app.StartFrame.Value, app.EndFrame.Value, app.OutputFolder.Value);

            % Display for visual confirmation
            figure(1);
            subplot(1,2,1)
            imshow(imread(app.FilePaths{1}, app.StartFrame.Value));
            title('First frame')
            subplot(1,2,2);
            imshow(imread(app.FilePaths{1}, app.EndFrame.Value));
            title('Last frame');

            if exist('msg', 'var')
                delete(msg);
                clear('msg');
            end
            msg = msgbox('User inputs processed'); 
        end

        % Button pushed function: Overlay
        function OverlayPushed(app, event)
            if exist('msg', 'var')
                delete(msg);
                clear('msg');
            end
            msg = msgbox('Overlaying movie frames');

            OverlayedRef = overlay(app.FilePaths{1}, app.TifInfo, app.StartFrame.Value, app.EndFrame.Value, app.N_frames);
            imwrite(OverlayedRef, [app.OutputPath, filesep, 'OverlayedRef.tif'], 'tif')
            OverlayedCa = overlay(app.FilePaths{2}, app.TifInfo, app.StartFrame.Value, app.EndFrame.Value, app.N_frames);
            imwrite(OverlayedCa, [app.OutputPath, filesep, 'OverlayedCa.tif'], 'tif')
            app.OverlayedImages = {OverlayedRef, OverlayedCa};
            FusedChannel = imfuse(mat2gray(app.OverlayedImages{1}), mat2gray(app.OverlayedImages{2}));
            imwrite(FusedChannel, [app.OutputPath, filesep, 'OverlayedBoth.tif'], 'tif')

            % Display for visual confirmation
            f1 = figure(1);
            f1.WindowState = 'maximized';
            subplot(1,3,1)
            imshow(OverlayedRef);
            title('Overlayed Reference Channel')
            subplot(1,3,2);
            imshow(OverlayedCa);
            title('Overlayed Calcium Channel'); 
            subplot(1,3,3);
            imshow(FusedChannel);
            title('Overlayed both channels');

            if exist('msg', 'var')
                delete(msg);
                clear('msg');
            end
            msgbox('Overlay complete')
        end

        % Button pushed function: SelectROI
        function SelectROIButtonPushed(app, event)
            RefOverlay = mat2gray(app.OverlayedImages{1});
            CaOverlay = mat2gray(app.OverlayedImages{2});
            BothChannel = imfuse(RefOverlay, CaOverlay);
            
            switch app.OverlayPanel.SelectedObject.Tag
                case 'Both'
                    DispCh = BothChannel;
                case 'Ca'
                    DispCh = CaOverlay;
                case 'Ref'
                    DispCh = RefOverlay;
            end

            close all;
            figure(1);
            imshow(DispCh)
            axis on;
            title('Do not close this figure window till end of script!');
            message = sprintf('Left click and hold to begin drawing.\nSimply lift the mouse button to finish');
            uiwait(msgbox(message));
            hFH = imfreehand();
            app.BinaryImage = hFH.createMask();
            ROI_boundary = app.BinaryImage;
            save([app.OutputPath, filesep, 'ROI_boundary.mat'],"ROI_boundary")
            close();
            save_ROI(DispCh, app.BinaryImage, app.OutputPath)

            if exist('msg', 'var')
                delete(msg);
                clear('msg');
            end
            msgbox('Region of interest saved')
        end

        % Button pushed function: PlotRawSignal
        function PlotRawSignalButtonPushed(app, event)
            if exist('msg', 'var')
                delete(msg);
                clear('msg');
            end
            msg = msgbox('Calculating and plotting raw signal');

            SignalRef = calculate_signal(app.StartFrame.Value,app.EndFrame.Value,app.FilePaths{1},app.BinaryImage);
            SignalCa = calculate_signal(app.StartFrame.Value,app.EndFrame.Value,app.FilePaths{2},app.BinaryImage);
            app.Signals = {SignalRef, SignalCa};
            close all;
            plot_raw_signal('on',SignalCa,SignalRef,app.N_frames,app.MeanDistance.Value, ...
                app.ExposureTime.Value,app.OutputPath)

            if exist('msg', 'var')
                delete(msg);
                clear('msg');
            end
            msg = msgbox('Raw signal plotted');
        end

        % Button pushed function: PlotNormalisedSignal
        function PlotNormalisedSignalButtonPushed(app, event)
            if exist('msg', 'var')
                delete(msg);
                clear('msg');
            end
            msg = msgbox('Calculating and plotting normalised signal');

            SignalRef = calculate_signal(app.StartFrame.Value,app.EndFrame.Value,app.FilePaths{1},app.BinaryImage);
            SignalCa = calculate_signal(app.StartFrame.Value,app.EndFrame.Value,app.FilePaths{2},app.BinaryImage);
            app.Signals = {SignalRef, SignalCa};
            switch app.SignalAnalysis.SelectedObject.Tag
                case 'Ref'
                    Baseline = min(SignalCa./SignalRef);
                    app.NormSignal = (SignalCa./SignalRef - Baseline)/Baseline;
                case 'Ca'
                    Baseline = min(SignalCa);
                    app.NormSignal = (SignalCa - Baseline)/Baseline;
            end

            close all;
            plot_normalised_signal('on',app.NormSignal,app.N_frames, ...
                app.MeanDistance.Value,app.ExposureTime.Value,app.OutputPath)

            if exist('msg', 'var')
                delete(msg);
                clear('msg');
            end
            msg = msgbox('Normalised signal plotted');
        end

        % Button pushed function: MakeGraphMovie
        function MakeGraphMovieButtonPushed(app, event)
            if exist('msg', 'var')
                delete(msg);
                clear('msg');
            end
            msg = msgbox('Creating graph movie');
            
            GraphMoviePath = [app.OutputPath,'\GraphMovie'];
            mkdir(GraphMoviePath);
            
            for i = 1:app.N_frames
                figure('Visible','off')
                time_scale = app.MeanDistance.Value*app.ExposureTime.Value/1000; % plot according to real time
                plot(0:time_scale:(i-1)*time_scale, app.NormSignal(1:i), 'k');
                title('Normalised Calcium Signal')
                xlabel('Time [s]');
                ylabel('Signal Intensity');
                axis([0,app.N_frames*time_scale,0,max(app.NormSignal)]);
                iName = string(i);
                imageName = fullfile(GraphMoviePath,iName);
                saveas(gcf,imageName,'tiff');
            end
            close all;

            if exist('msg', 'var')
                delete(msg);
                clear('msg');
            end
            msg = msgbox('Graph movie created');
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            if exist('msg', 'var')
                delete(msg);
                clear('msg');
            end
            msg = msgbox('Saving session settings');

            switch app.OutputPanel.SelectedObject.Tag
                case 'Session'
                    save_signal_analysis(app.FolderPath.Value, app.FilenameRefCh.Value, app.FilenameCaCh.Value, ...
                        app.StartFrame.Value, app.EndFrame.Value, app.OutputFolder.Value, ...
                        app.ExposureTime.Value, app.MeanDistance.Value, app.OverlayPanel.SelectedObject.Tag, app.SignalAnalysis.SelectedObject.Tag)
            end

            NormalisedSignal = app.NormSignal;
            save([app.OutputPath, filesep, 'NormalisedSignal.mat'],"NormalisedSignal")

            if exist('msg', 'var')
                delete(msg);
                clear('msg');
            end
            msg = msgbox('Session settings saved');
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            close all;
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.OutputPanel = uibuttongroup(app.UIFigure);
            app.OutputPanel.Title = 'Output';
            app.Session = uiradiobutton(app.OutputPanel);
            app.Session.Tag = 'Session';
            app.Session.Text = 'Save session settings';
            app.Session.Value = true;
            app.SaveButton = uibutton(app.OutputPanel, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.OutputNameLabel = uilabel(app.OutputPanel);
            app.OutputNameLabel.Text = 'Output Folder';
            app.OutputFolder = uieditfield(app.OutputPanel, 'text');
            app.OutputFolder.Value = 'CaSignalVsTime';
            app.InputPanel = uipanel(app.UIFigure);
            app.InputPanel.Title = 'Input';
            app.DirectoryEditFieldLabel = uilabel(app.InputPanel);
            app.DirectoryEditFieldLabel.Text = 'Directory';
            app.Channel1Label = uilabel(app.InputPanel);
            app.Channel1Label.Text = 'Ca Ch';
            app.SetInputs = uibutton(app.InputPanel, 'push');
            app.SetInputs.ButtonPushedFcn = createCallbackFcn(app, @SetInputsPushed, true);
            app.SetInputs.Text = 'Set';
            app.EndFrame = uieditfield(app.InputPanel, 'numeric');
            app.StartFrame = uieditfield(app.InputPanel, 'numeric');
            app.StartFrame.Limits = [0 Inf];
            app.StartFrame.RoundFractionalValues = 'on';
            app.StartFrame.Value = 1;
            app.Channel2Label = uilabel(app.InputPanel);
            app.Channel2Label.Text = 'Ref Ch';
            app.BrowseCaCh = uibutton(app.InputPanel, 'push');
            app.BrowseCaCh.ButtonPushedFcn = createCallbackFcn(app, @BrowseCaChButtonPushed, true);
            app.BrowseCaCh.Text = 'Browse';
            app.FilenameCaCh = uieditfield(app.InputPanel, 'text');
            app.BrowseRefCh = uibutton(app.InputPanel, 'push');
            app.BrowseRefCh.ButtonPushedFcn = createCallbackFcn(app, @BrowseRefChButtonPushed, true);
            app.BrowseRefCh.Text = 'Browse';
            app.FilenameRefCh = uieditfield(app.InputPanel, 'text');
            app.BrowseFolder = uibutton(app.InputPanel, 'push');
            app.BrowseFolder.ButtonPushedFcn = createCallbackFcn(app, @BrowseButtonPushed, true);
            app.BrowseFolder.Text = 'Browse';
            app.FolderPath = uieditfield(app.InputPanel, 'text');
            app.FolderPath.ValueChangedFcn = createCallbackFcn(app, @BrowseButtonPushed, true);
            app.StartFrameLabel = uilabel(app.InputPanel);
            app.StartFrameLabel.Text = 'Start Frame';
            app.EndFrameLabel = uilabel(app.InputPanel);
            app.EndFrameLabel.Text = 'End Frame';
            app.OverlayPanel = uibuttongroup(app.UIFigure);
            app.OverlayPanel.Title = 'Overlay';
            app.BothchannelsButton = uiradiobutton(app.OverlayPanel);
            app.BothchannelsButton.Tag = 'Both';
            app.BothchannelsButton.Text = 'Both channels';
            app.BothchannelsButton.Value = true;
            app.CalciumchannelButton = uiradiobutton(app.OverlayPanel);
            app.CalciumchannelButton.Tag = 'Ca';
            app.CalciumchannelButton.Text = 'Calcium channel';
            app.ReferencechannelButton = uiradiobutton(app.OverlayPanel);
            app.ReferencechannelButton.Tag = 'Ref';
            app.ReferencechannelButton.Text = 'Reference channel';
            app.Overlay = uibutton(app.OverlayPanel, 'push');
            app.Overlay.ButtonPushedFcn = createCallbackFcn(app, @OverlayPushed, true);
            app.Overlay.Text = 'Overlay';
            app.SelectROI = uibutton(app.OverlayPanel, 'push');
            app.SelectROI.ButtonPushedFcn = createCallbackFcn(app, @SelectROIButtonPushed, true);
            app.SelectROI.Text = 'Draw Region of Interest';
            app.SignalAnalysis = uibuttongroup(app.UIFigure);
            app.SignalAnalysis.Title = 'Signal Analysis';
            app.ReferenceNormalise = uiradiobutton(app.SignalAnalysis);
            app.ReferenceNormalise.Tag = 'Ref';
            app.ReferenceNormalise.Text = 'Normalise against reference';
            app.ReferenceNormalise.Value = true;
            app.CalciumNormalise = uiradiobutton(app.SignalAnalysis);
            app.CalciumNormalise.Tag = 'Ca';
            app.CalciumNormalise.Text = 'Normalise against calcium';
            app.SamplingRateLabel = uilabel(app.SignalAnalysis);
            app.SamplingRateLabel.Text = 'Exposure Time [ms]';
            app.ExposureTime = uieditfield(app.SignalAnalysis, 'numeric');
            app.ExposureTime.Limits = [0 Inf];
            app.ExposureTime.RoundFractionalValues = 'on';
            app.ExposureTime.Value = 24;
            app.MeanDistLabel = uilabel(app.SignalAnalysis);
            app.MeanDistLabel.Text = 'Cycle Length [frames]';
            app.MeanDistance = uieditfield(app.SignalAnalysis, 'numeric');
            app.MeanDistance.Limits = [1 Inf];
            app.MeanDistance.Value = 13.5;
            app.PlotRawSignal = uibutton(app.SignalAnalysis, 'push');
            app.PlotRawSignal.ButtonPushedFcn = createCallbackFcn(app, @PlotRawSignalButtonPushed, true);
            app.PlotRawSignal.Text = 'Plot Preliminary Signal';
            app.MakeGraphMovie = uibutton(app.SignalAnalysis, 'push');
            app.MakeGraphMovie.ButtonPushedFcn = createCallbackFcn(app, @MakeGraphMovieButtonPushed, true);
            app.MakeGraphMovie.Text = 'Make Graph Movie';
            app.PlotNormalisedSignal = uibutton(app.SignalAnalysis, 'push');
            app.PlotNormalisedSignal.ButtonPushedFcn = createCallbackFcn(app, @PlotNormalisedSignalButtonPushed, true);
            app.PlotNormalisedSignal.Text = 'Plot Normalised Calcium Level';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SignalAnalysis_App

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end